classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Image                   matlab.ui.control.Image
        TechMahindraSmartTyredetectionLabel  matlab.ui.control.Label
        StatusLabel             matlab.ui.control.Label
        RunSimulationButton     matlab.ui.control.Button
        TEditField              matlab.ui.control.NumericEditField
        TTemparatureLabel       matlab.ui.control.Label
        kEditField              matlab.ui.control.NumericEditField
        kLeakageRateLabel       matlab.ui.control.Label
        P0EditField             matlab.ui.control.NumericEditField
        P0initialPressureLabel  matlab.ui.control.Label
        UIAxes                  matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

        end

        % Callback function: RunSimulationButton, UIFigure, UIFigure, 
        % ...and 6 other components
        function RunSimulationButtonPushed(app, event)
            % Get user inputs from fields with validation
P0 = app.P0EditField.Value;  % Initial Pressure (psi)
k  = app.kEditField.Value;   % Leakage Rate 
T  = app.TEditField.Value;   % Temperature (Kelvin)

% Validate inputs
if P0 <= 0 || k < 0 || T <= 0
    app.StatusLabel.Text = 'Error: Invalid input values!';
    app.StatusLabel.FontColor = [1 0 0];
    return;
end

% Simulation parameters
P_flat = 20;                  % Flat tyre threshold (psi)
P_warning = 25;               % Warning threshold (psi)
dt = 0.1;                     % Time step (minutes)
time = 0:dt:30;               % Simulation time in minutes

% Enhanced pressure decay model with realistic effects
P_ideal = P0 * exp(-k * time);

% Add realistic noise and environmental effects
noise = 0.2 * randn(size(time));                    % Sensor noise
road_effects = 0.15 * sin(2*pi*time/1.5) + ...      % Road vibrations
               0.08 * sin(2*pi*time/4);             % Vehicle dynamics
temperature_fluctuations = 0.1 * sin(2*pi*time/10); % Ambient changes

% Combined pressure with realistic variations
P = P_ideal + noise + road_effects + temperature_fluctuations;
P = max(5, P); % Prevent unrealistic negative pressures

% Temperature correction using Ideal Gas Law (PV = nRT)
T_ref = 298.15; % Reference temperature (25°C in Kelvin)
P_norm = P .* (T_ref ./ T);

% Calculate pressure derivative for leak rate analysis
dPdt = gradient(P_norm, time);

% Advanced condition detection with multiple criteria
P_final = P_norm(end);
avg_leak_rate = mean(dPdt);
min_pressure = min(P_norm);
max_leak_rate = min(dPdt); % Most negative value

% Enhanced status determination with multiple factors
if P_final > 28 && avg_leak_rate > -0.02 && min_pressure > 26
    status = '✅ HEALTHY';
    status_color = [0 0.5 0];
    line_color = [0 0.8 0];
elseif P_final > P_flat && avg_leak_rate <= -0.02 && avg_leak_rate > -0.08
    status = '⚠️ SLOW LEAK DETECTED';
    status_color = [1 0.6 0];
    line_color = [1 0.6 0];
elseif P_final > P_flat && avg_leak_rate <= -0.08
    status = '🚨 PUNCTURE SUSPECTED';
    status_color = [1 0.3 0];
    line_color = [1 0.3 0];
elseif P_final <= P_flat || max_leak_rate <= -0.15
    status = '🔥 FLAT TYRE - IMMEDIATE ACTION';
    status_color = [1 0 0];
    line_color = [1 0 0];
else
    status = '🔍 MONITORING REQUIRED';
    status_color = [0.5 0.5 0.5];
    line_color = [0.5 0.5 0.5];
end

% Calculate safety score (0-100)
safety_score = max(0, min(100, (P_final - 15) / (35 - 15) * 100));
if avg_leak_rate < -0.05
    safety_score = safety_score * 0.7; % Penalize for leaks
end

% Display enhanced status information
status_text = sprintf(['Status: %s\n' ...
                      'Final Pressure: %.1f psi\n' ...
                      'Leak Rate: %.3f psi/min\n' ...
                      'Safety Score: %.0f/100'], ...
                      status, P_final, avg_leak_rate, safety_score);
app.StatusLabel.Text = status_text;
app.StatusLabel.FontColor = status_color;

% Enhanced plotting with comprehensive visualization
cla(app.UIAxes); % Clear previous plot

% Main pressure curve
plot(app.UIAxes, time, P_norm, 'Color', line_color, ...
     'LineWidth', 2.5, 'DisplayName', 'Tyre Pressure');

hold(app.UIAxes, 'on');

% Threshold lines with labels
yline(app.UIAxes, 35, '--', 'Color', [0 0.8 0], ...
      'LineWidth', 1, 'DisplayName', 'Max Safe', ...
      'Label', 'Max Safe', 'LabelHorizontalAlignment', 'left', ...
      'Alpha', 0.7);

yline(app.UIAxes, 28, '--', 'Color', [0.2 0.7 0.2], ...
      'LineWidth', 1, 'DisplayName', 'Optimal Range', ...
      'Alpha', 0.5);

yline(app.UIAxes, P_warning, '--', 'Color', [1 0.5 0], ...
      'LineWidth', 1.5, 'DisplayName', 'Warning Level', ...
      'Label', 'Warning', 'LabelHorizontalAlignment', 'left', ...
      'Alpha', 0.8);

yline(app.UIAxes, P_flat, '--', 'Color', [1 0 0], ...
      'LineWidth', 2, 'DisplayName', 'Critical Level', ...
      'Label', 'FLAT TYRE', 'LabelHorizontalAlignment', 'left', ...
      'Alpha', 0.9);

% Plot ideal pressure for comparison (without noise)
plot(app.UIAxes, time, P0 * exp(-k * time) .* (T_ref ./ T), ...
     ':', 'Color', [0.7 0.7 0.7], 'LineWidth', 1, ...
     'DisplayName', 'Ideal Pressure (No Noise)');

% Plot customization
xlabel(app.UIAxes, 'Time (minutes)');
ylabel(app.UIAxes, 'Pressure (psi)');
title(app.UIAxes, sprintf('Smart Tyre Monitoring - %s', status), 'FontSize', 12, 'FontWeight', 'bold');
legend(app.UIAxes, 'Location', 'northeast', 'Box', 'off', 'FontSize', 8);
grid(app.UIAxes, 'on');

% Set appropriate axis limits with margins
y_margin = 2;
x_margin = 2;
y_limits = [max(5, min(P_norm)-y_margin), min(40, max(P_norm)+y_margin)];
x_limits = [0, max(time)+x_margin];

ylim(app.UIAxes, y_limits);
xlim(app.UIAxes, x_limits);

% Add annotation for critical conditions
if contains(status, 'FLAT TYRE') || contains(status, 'PUNCTURE')
    annotation_text = sprintf('Alert! Leak rate: %.3f psi/min', avg_leak_rate);
    text(app.UIAxes, max(time)/2, mean(y_limits), annotation_text, ...
         'HorizontalAlignment', 'center', 'VerticalAlignment', 'middle', ...
         'BackgroundColor', [1 0.8 0.8], 'EdgeColor', 'red', ...
         'FontSize', 10, 'FontWeight', 'bold');
end

hold(app.UIAxes, 'off');

% Add sound alert for critical conditions
if contains(status, 'FLAT TYRE')
    beep; % Audible alert for critical condition
end

% Log simulation results for debugging
fprintf('\n=== Simulation Results ===\n');
fprintf('Initial Pressure: %.1f psi\n', P0);
fprintf('Leakage Rate (k): %.4f\n', k);
fprintf('Temperature: %.1f K (%.1f°C)\n', T, T-273.15);
fprintf('Final Pressure: %.1f psi\n', P_final);
fprintf('Average Leak Rate: %.4f psi/min\n', avg_leak_rate);
fprintf('Minimum Pressure: %.1f psi\n', min_pressure);
fprintf('Detected Condition: %s\n', status);
fprintf('Safety Score: %.0f/100\n', safety_score);
fprintf('==========================\n\n');
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)

        end

        % Button down function: UIFigure
        function UIFigureButtonDown(app, event)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @RunSimulationButtonPushed, true);
            app.UIFigure.WindowButtonUpFcn = createCallbackFcn(app, @RunSimulationButtonPushed, true);
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @RunSimulationButtonPushed, true);
            app.UIFigure.WindowScrollWheelFcn = createCallbackFcn(app, @RunSimulationButtonPushed, true);
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @UIFigureButtonDown, true);
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @RunSimulationButtonPushed, true);
            app.UIFigure.WindowKeyReleaseFcn = createCallbackFcn(app, @RunSimulationButtonPushed, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @RunSimulationButtonPushed, true);
            app.UIFigure.KeyReleaseFcn = createCallbackFcn(app, @RunSimulationButtonPushed, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Puncture detection')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Pressure')
            zlabel(app.UIAxes, 'Z')
            app.P0initialPressureLabel = uilabel(app.UIFigure);
            app.P0initialPressureLabel.Text = '.P0(initial Pressure)';
            app.P0EditField = uieditfield(app.UIFigure, 'numeric');
            app.kLeakageRateLabel = uilabel(app.UIFigure);
            app.kLeakageRateLabel.Text = '.k (Leakage Rate)';
            app.kEditField = uieditfield(app.UIFigure, 'numeric');
            app.TTemparatureLabel = uilabel(app.UIFigure);
            app.TTemparatureLabel.Text = 'T (Temparature)';
            app.TEditField = uieditfield(app.UIFigure, 'numeric');
            app.RunSimulationButton = uibutton(app.UIFigure, 'push');
            app.RunSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @RunSimulationButtonPushed, true);
            app.RunSimulationButton.Text = 'Run Simulation';
            app.StatusLabel = uilabel(app.UIFigure);
            app.StatusLabel.Text = 'Healthy / Puncture / Flat';
            app.TechMahindraSmartTyredetectionLabel = uilabel(app.UIFigure);
            app.TechMahindraSmartTyredetectionLabel.Text = 'Tech Mahindra Smart Tyre detection';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'tech mahindra logo.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end